classdef GUI_plots_roots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        ChoosedatafortheplotandclickStartbuttonLabel  matlab.ui.control.Label
        MaxIterationEditField       matlab.ui.control.NumericEditField
        MaxIterationEditFieldLabel  matlab.ui.control.Label
        ApproximationButtonGroup    matlab.ui.container.ButtonGroup
        e10Button                   matlab.ui.control.RadioButton
        e6Button                    matlab.ui.control.RadioButton
        e8Button                    matlab.ui.control.RadioButton
        a14EditField                matlab.ui.control.NumericEditField
        a14EditFieldLabel           matlab.ui.control.Label
        a13EditField                matlab.ui.control.NumericEditField
        a13EditFieldLabel           matlab.ui.control.Label
        a12EditField                matlab.ui.control.NumericEditField
        a12EditFieldLabel           matlab.ui.control.Label
        a11EditField                matlab.ui.control.NumericEditField
        a11EditFieldLabel           matlab.ui.control.Label
        a10EditField                matlab.ui.control.NumericEditField
        a10EditFieldLabel           matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        a9EditField                 matlab.ui.control.NumericEditField
        a9EditFieldLabel            matlab.ui.control.Label
        a8EditField                 matlab.ui.control.NumericEditField
        a8EditFieldLabel            matlab.ui.control.Label
        a7EditField                 matlab.ui.control.NumericEditField
        a7EditFieldLabel            matlab.ui.control.Label
        a6EditField                 matlab.ui.control.NumericEditField
        a6EditFieldLabel            matlab.ui.control.Label
        a5EditField                 matlab.ui.control.NumericEditField
        a5EditFieldLabel            matlab.ui.control.Label
        a4EditField                 matlab.ui.control.NumericEditField
        a4EditFieldLabel            matlab.ui.control.Label
        a3EditField                 matlab.ui.control.NumericEditField
        a3EditFieldLabel            matlab.ui.control.Label
        a2EditField                 matlab.ui.control.NumericEditField
        a2EditFieldLabel            matlab.ui.control.Label
        a1EditField_2               matlab.ui.control.NumericEditField
        a1EditField_2Label          matlab.ui.control.Label
        a0EditField                 matlab.ui.control.NumericEditField
        a0EditFieldLabel            matlab.ui.control.Label
        nSlider                     matlab.ui.control.Slider
        nSliderLabel                matlab.ui.control.Label
        PlotTab                     matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        n_val
        a_val = zeros(15)
        poly
        zeroo
        approx = 1e-8
        max_iter = 200
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: a0EditField
        function a0EditFieldValueChanged(app, event)
            app.a_val(1) = app.a0EditField.Value;
            
        end

        % Value changed function: a1EditField_2
        function a1EditField_2ValueChanged(app, event)
            app.a_val(2) = app.a1EditField_2.Value;
            
        end

        % Value changed function: nSlider
        function nSliderValueChanged(app, event)
            app.n_val = app.nSlider.Value;
        end

        % Value changed function: a2EditField
        function a2EditFieldValueChanged(app, event)
            app.a_val(3) = app.a2EditField.Value;
            
        end

        % Value changed function: a3EditField
        function a3EditFieldValueChanged(app, event)
            app.a_val(4) = app.a3EditField.Value;
            
        end

        % Value changed function: a4EditField
        function a4EditFieldValueChanged(app, event)
            app.a_val(5) = app.a4EditField.Value;
            
        end

        % Value changed function: a5EditField
        function a5EditFieldValueChanged(app, event)
            app.a_val(6) = app.a5EditField.Value;
            
        end

        % Value changed function: a6EditField
        function a6EditFieldValueChanged(app, event)
            app.a_val(7) = app.a6EditField.Value;
            
        end

        % Value changed function: a7EditField
        function a7EditFieldValueChanged(app, event)
            app.a_val(8) = app.a7EditField.Value;
            
        end

        % Value changed function: a8EditField
        function a8EditFieldValueChanged(app, event)
            app.a_val(9) = app.a8EditField.Value;
            
        end

        % Value changed function: a9EditField
        function a9EditFieldValueChanged(app, event)
            app.a_val(10) = app.a9EditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.poly = ReturnPolynomial([-1:0.1:1], app.n_val, app.a_val);
            app.zeroo = unique(Newton(app.n_val,app.a_val,app.max_iter,[-1:0.1:1],app.approx));
            app.zeroo = app.zeroo(app.zeroo <= 1 & app.zeroo >=-1);
            plot(app.UIAxes, [-1:0.1:1], app.poly, 'LineWidth', 2);
            hold(app.UIAxes, 'on');
            scatter(app.UIAxes, app.zeroo,zeros (size(app.zeroo)),50, 'r', 'filled', 'DisplayName', 'Zeros from Newton');
            title(app.UIAxes, 'Polynomial plot for x from [-1,1]');
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'polynomial value');
            grid(app.UIAxes, 'on');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: a10EditField
        function a10EditFieldValueChanged(app, event)
            app.a_val(11) = app.a10EditField.Value;
            
        end

        % Value changed function: a11EditField
        function a11EditFieldValueChanged(app, event)
            app.a_val(12) = app.a11EditField.Value;
            
        end

        % Value changed function: a12EditField
        function a12EditFieldValueChanged(app, event)
            app.a_val(13) = app.a12EditField.Value;
            
        end

        % Value changed function: a13EditField
        function a13EditFieldValueChanged(app, event)
            app.a_val(14) = app.a13EditField.Value;
            
        end

        % Value changed function: a14EditField
        function a14EditFieldValueChanged(app, event)
            app.a_val(15) = app.a14EditField.Value;
            
        end

        % Selection changed function: ApproximationButtonGroup
        function ApproximationButtonGroupSelectionChanged(app, event)
            selectedButton = app.ApproximationButtonGroup.SelectedObject;
            if selectedButton == app.e10Button
                app.approx = 1e-10;
            elseif selectedButton == app.e6Button
                app.approx = 1e-6;
            else
                app.approx = 1e-8;
            end    
        end

        % Value changed function: MaxIterationEditField
        function MaxIterationEditFieldValueChanged(app, event)
            app.max_iter = app.MaxIterationEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.nSliderLabel = uilabel(app.DataTab);
            app.nSliderLabel.Text = 'n';
            app.nSlider = uislider(app.DataTab);
            app.nSlider.Limits = [0 14];
            app.nSlider.MajorTicks = [0 2 4 6 8 10 12 14];
            app.nSlider.ValueChangedFcn = createCallbackFcn(app, @nSliderValueChanged, true);
            app.nSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14];
            app.a0EditFieldLabel = uilabel(app.DataTab);
            app.a0EditFieldLabel.Text = 'a0';
            app.a0EditField = uieditfield(app.DataTab, 'numeric');
            app.a0EditField.ValueChangedFcn = createCallbackFcn(app, @a0EditFieldValueChanged, true);
            app.a1EditField_2Label = uilabel(app.DataTab);
            app.a1EditField_2Label.Text = 'a1';
            app.a1EditField_2 = uieditfield(app.DataTab, 'numeric');
            app.a1EditField_2.ValueChangedFcn = createCallbackFcn(app, @a1EditField_2ValueChanged, true);
            app.a2EditFieldLabel = uilabel(app.DataTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DataTab, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @a2EditFieldValueChanged, true);
            app.a3EditFieldLabel = uilabel(app.DataTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DataTab, 'numeric');
            app.a3EditField.ValueChangedFcn = createCallbackFcn(app, @a3EditFieldValueChanged, true);
            app.a4EditFieldLabel = uilabel(app.DataTab);
            app.a4EditFieldLabel.Text = 'a4';
            app.a4EditField = uieditfield(app.DataTab, 'numeric');
            app.a4EditField.ValueChangedFcn = createCallbackFcn(app, @a4EditFieldValueChanged, true);
            app.a5EditFieldLabel = uilabel(app.DataTab);
            app.a5EditFieldLabel.Text = 'a5';
            app.a5EditField = uieditfield(app.DataTab, 'numeric');
            app.a5EditField.ValueChangedFcn = createCallbackFcn(app, @a5EditFieldValueChanged, true);
            app.a6EditFieldLabel = uilabel(app.DataTab);
            app.a6EditFieldLabel.Text = 'a6';
            app.a6EditField = uieditfield(app.DataTab, 'numeric');
            app.a6EditField.ValueChangedFcn = createCallbackFcn(app, @a6EditFieldValueChanged, true);
            app.a7EditFieldLabel = uilabel(app.DataTab);
            app.a7EditFieldLabel.Text = 'a7';
            app.a7EditField = uieditfield(app.DataTab, 'numeric');
            app.a7EditField.ValueChangedFcn = createCallbackFcn(app, @a7EditFieldValueChanged, true);
            app.a8EditFieldLabel = uilabel(app.DataTab);
            app.a8EditFieldLabel.Text = 'a8';
            app.a8EditField = uieditfield(app.DataTab, 'numeric');
            app.a8EditField.ValueChangedFcn = createCallbackFcn(app, @a8EditFieldValueChanged, true);
            app.a9EditFieldLabel = uilabel(app.DataTab);
            app.a9EditFieldLabel.Text = 'a9';
            app.a9EditField = uieditfield(app.DataTab, 'numeric');
            app.a9EditField.ValueChangedFcn = createCallbackFcn(app, @a9EditFieldValueChanged, true);
            app.StartButton = uibutton(app.DataTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.a10EditFieldLabel = uilabel(app.DataTab);
            app.a10EditFieldLabel.Text = 'a10';
            app.a10EditField = uieditfield(app.DataTab, 'numeric');
            app.a10EditField.ValueChangedFcn = createCallbackFcn(app, @a10EditFieldValueChanged, true);
            app.a11EditFieldLabel = uilabel(app.DataTab);
            app.a11EditFieldLabel.Text = 'a11';
            app.a11EditField = uieditfield(app.DataTab, 'numeric');
            app.a11EditField.ValueChangedFcn = createCallbackFcn(app, @a11EditFieldValueChanged, true);
            app.a12EditFieldLabel = uilabel(app.DataTab);
            app.a12EditFieldLabel.Text = 'a12';
            app.a12EditField = uieditfield(app.DataTab, 'numeric');
            app.a12EditField.ValueChangedFcn = createCallbackFcn(app, @a12EditFieldValueChanged, true);
            app.a13EditFieldLabel = uilabel(app.DataTab);
            app.a13EditFieldLabel.Text = 'a13';
            app.a13EditField = uieditfield(app.DataTab, 'numeric');
            app.a13EditField.ValueChangedFcn = createCallbackFcn(app, @a13EditFieldValueChanged, true);
            app.a14EditFieldLabel = uilabel(app.DataTab);
            app.a14EditFieldLabel.Text = 'a14';
            app.a14EditField = uieditfield(app.DataTab, 'numeric');
            app.a14EditField.ValueChangedFcn = createCallbackFcn(app, @a14EditFieldValueChanged, true);
            app.ApproximationButtonGroup = uibuttongroup(app.DataTab);
            app.ApproximationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ApproximationButtonGroupSelectionChanged, true);
            app.ApproximationButtonGroup.Title = 'Approximation';
            app.e8Button = uiradiobutton(app.ApproximationButtonGroup);
            app.e8Button.Text = '1e-8';
            app.e8Button.Value = true;
            app.e6Button = uiradiobutton(app.ApproximationButtonGroup);
            app.e6Button.Text = '1e-6';
            app.e10Button = uiradiobutton(app.ApproximationButtonGroup);
            app.e10Button.Text = '1e-10';
            app.MaxIterationEditFieldLabel = uilabel(app.DataTab);
            app.MaxIterationEditFieldLabel.Text = 'Max Iteration';
            app.MaxIterationEditField = uieditfield(app.DataTab, 'numeric');
            app.MaxIterationEditField.Limits = [0 Inf];
            app.MaxIterationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIterationEditFieldValueChanged, true);
            app.MaxIterationEditField.Value = 200;
            app.ChoosedatafortheplotandclickStartbuttonLabel = uilabel(app.DataTab);
            app.ChoosedatafortheplotandclickStartbuttonLabel.Text = 'Choose data for the plot and click Start button';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_plots_roots

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end